classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        StartButton              matlab.ui.control.Button
        GlucagoneGauge           matlab.ui.control.LinearGauge
        GlucagoneGaugeLabel      matlab.ui.control.Label
        InsulinGauge             matlab.ui.control.LinearGauge
        InsulinGaugeLabel        matlab.ui.control.Label
        GlucoseGauge             matlab.ui.control.Gauge
        GlucoseGaugeLabel        matlab.ui.control.Label
        mmolLLabel_3             matlab.ui.control.Label
        mmolLLabel_2             matlab.ui.control.Label
        mmolLLabel               matlab.ui.control.Label
        Label_glucagone          matlab.ui.control.Label
        Label_insulin            matlab.ui.control.Label
        Label_level              matlab.ui.control.Label
        GlucagoneLevelLabel      matlab.ui.control.Label
        InsulinLevelLabel        matlab.ui.control.Label
        GlucoseLevelLabel        matlab.ui.control.Label
        TextArea                 matlab.ui.control.TextArea
        InfusionPumpsystemLabel  matlab.ui.control.Label
        Lamp_Green               matlab.ui.control.Lamp
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            bloodSugarLevel = 90; % Initial blood sugar level in mmol/L
            carbohydratesIntake = 0; % Initial carbohydrates intake in grams
            time = 0; % Initial time
            insulinInjection = 0;
            glucagonInjection = 0;
            t2 = 0;
            t3 = 0;
            %insulinamount = 200;
            %glucagonamount = 200;

            % Create a figure for plotting
            figure;

            subplot(3, 1, 1);
            bloodSugarPlot = plot(time, bloodSugarLevel, 'b');
            xlabel('Time (s)');
            ylabel('Blood Sugar Level (mmol/L)');
            title('Blood Sugar Level Simulation');
            grid on;

            subplot(3, 1, 2);

            insulinPlot = plot(time, insulinInjection, 'r');
            ylim([-1, 3]);
            xlabel('Time (s)');
            ylabel('Insulin Injection Level (mmol/L)');
            title('Insulin Status');
            grid on;

            subplot(3, 1, 3);

            glucagonPlot = plot(time, glucagonInjection, 'g');
            ylim([-1, 3]);
            xlabel('Time (s)');
            ylabel('Glucagon Injection Level (mmol/L)');
            title('Glucagon Status');
            grid on;


            for i = 1:2
                pause(2);
                time = time + 1; % Increment time by 2 seconds
                set(bloodSugarPlot, 'XData', [get(bloodSugarPlot, 'XData'), time], 'YData', [get(bloodSugarPlot, 'YData'), bloodSugarLevel]);
                drawnow limitrate;
                app.Label_level.Text = num2str(bloodSugarLevel);
            end

            for o = 1:2
                pause(2);
                t2 =  t2 + 1; % Increment time by 2 seconds
                set(insulinPlot, 'XData', [get(insulinPlot, 'XData'), t2], 'YData', [get(insulinPlot, 'YData'), insulinInjection]);
                drawnow limitrate;
                app.Label_level.Text = num2str(bloodSugarLevel);
            end


            for p = 1:2
                pause(2);
                t3 = t3 + 1; % Increment time by 2 seconds
                set(glucagonPlot, 'XData', [get(glucagonPlot, 'XData'), t3], 'YData', [get(glucagonPlot, 'YData'), glucagonInjection]);
                drawnow limitrate;
                app.Label_level.Text = num2str(bloodSugarLevel);
            end


            while true
                % % Update plot data
                %
                % disp(['Blood Sugar Level: ', num2str(bloodSugarLevel), ' mmol/L']);
                % action = input('Press B (40g), L (50g), D (55g), or X to exit: ', 's');
                %
                % if action == 'X'
                %     disp('Exiting the program...');
                %     break;
                % elseif action == 'B'
                %     carbohydratesIntake = 30;
                % elseif action == 'L'
                %     carbohydratesIntake = 50;
                % elseif action == 'D'
                %     carbohydratesIntake = 55;
                % else
                %     disp('Invalid input! Please try again.');
                %     continue;
                % end
                %
                % Update blood sugar level according to carbohydrates intake
                bloodSugarLevel = 55 * 30/ 10;


                %set(bloodSugarPlot, 'XData', [get(bloodSugarPlot, 'XData'), 1], 'YData', [get(bloodSugarPlot, 'YData'), bloodSugarLevel]);
                %drawnow limitrate;
                % pause(2); % Pause for 2 seconds
                % disp(['Blood Sugar Level: ', num2str(bloodSugarLevel), ' mmol/L']);


                % Check if blood sugar level exceeds the limit
                c = 0;
                while bloodSugarLevel > 180
                    app.InsulinGauge.Value=bloodSugarLevel;
                    app.InsulinGauge.Value=glucagonInjection;
                    app.Lamp_Green.Color=[1,1,0];
                    app.Label_insulin.Text=num2str(level);
                    app.Label_level.Text = num2str(bloodSugarLevel);
                    answer = questdlg(' Your Glucose level very High ,Please add Insulin ,For Press Yes ! ',...
                        'Level High!', ...
                        'Yes','NO',' ');
                    % Handle response
                    switch answer
                        case 'NO'
                            break;
                        case 'Yes'
                             app.Lamp_Green.Color=[0,1,0];
                            pause(1);
                            time = time + 2;
                            % Increment time by 2 seconds
                            insulinDose = 2; % Amount of insulin to be injected
                            insulinInjection = insulinDose;
                            %insulinamount = insulinamount - 2;
                            bloodSugarLevel = bloodSugarLevel - insulinDose;

                            set(bloodSugarPlot, 'XData', [get(bloodSugarPlot, 'XData'), time], 'YData', [get(bloodSugarPlot, 'YData'), bloodSugarLevel]);
                            drawnow limitrate;

                            if c == 0

                                for p = 1:2
                                    t = 0;
                                    set(insulinPlot, 'XData', [get(insulinPlot, 'XData'), time], 'YData', [get(insulinPlot, 'YData'), t]);
                                    drawnow limitrate;
                                end
                            else
                                app.InsulinGauge.Value=insulinInjection
                                set(insulinPlot, 'XData', [get(insulinPlot, 'XData'), t2], 'YData', [get(insulinPlot, 'YData'), insulinInjection]);
                                drawnow limitrate;
                            end


                            t2 = t2 + 2;
                            app.Label_insulin.Text = num2str(insulinDose);
                            c = c + 1;
                    end
                end

                %insulinInjection = 0;
                %set(insulinPlot, 'XData', [get(insulinPlot, 'XData'), time], 'YData', [get(insulinPlot, 'YData'), insulinInjection]);
                %   drawnow limitrate;
                if bloodSugarLevel == 180
                    insulinInjection = 0;

                    ctime = time - 2;
                    c1 = 0;
                    while c1 < 2
                        for p = 1:1

                            set(insulinPlot, 'XData', [get(insulinPlot, 'XData'), ctime], 'YData', [get(insulinPlot, 'YData'), 0]);
                            drawnow limitrate;
                            ctime = ctime + 2;
                        end
                        if c1 == 1
                            set(insulinPlot, 'XData', [get(insulinPlot, 'XData'), ctime], 'YData', [get(insulinPlot, 'YData'), insulinInjection]);
                            drawnow limitrate;
                        end
                        c1 = c1 + 1;
                    end
                end

                glucagonInjection = 0;
                set(glucagonPlot, 'XData', [get(glucagonPlot, 'XData'), time], 'YData', [get(glucagonPlot, 'YData'), glucagonInjection]);
                drawnow limitrate;


                % Blood sugar level decreases over time
                while bloodSugarLevel > 90
                    bloodSugarLevel = bloodSugarLevel - 2;
                    pause(1); % Pause for 2 seconds
                    time = time + 2; % Increment time by 2 seconds

                    set(bloodSugarPlot, 'XData', [get(bloodSugarPlot, 'XData'), time], 'YData', [get(bloodSugarPlot, 'YData'), bloodSugarLevel]);
                    drawnow limitrate;

                   app.GlucoseGauge.Value=bloodSugarLevel;
                   app.Label_level.Text = num2str(bloodSugarLevel);
                end

                %
                insulinInjection = 0;
                t2 = time;

                set(insulinPlot, 'XData', [get(insulinPlot, 'XData'), t2 ], 'YData', [get(insulinPlot, 'YData'), insulinInjection]);
                drawnow limitrate;

                glucagonInjection = 0;
                set(glucagonPlot, 'XData', [get(glucagonPlot, 'XData'), time], 'YData', [get(glucagonPlot, 'YData'), glucagonInjection]);
                drawnow limitrate;
                %
                pause(2);
                % If blood sugar level drops below 70, inject glucagon to maintain it
                g = 0;
                while bloodSugarLevel < 90
                    app.GlucoseGauge.Value=bloodSugarLevel
                    app.Lamp_Green.Color=[1,0,0];
                    answer = questdlg(' Your Glucose level very low ,Please add Glucagone ,For Press Yes ! ',...
                        'Level Low!', ...
                        'Yes','NO',' ');
                    % Handle response
                    switch answer
                        case 'NO'
                            break;
                        case 'Yes'
                            app.Lamp_Green.Color=[0,1,0];
                            pause(1);
                            % time = time + 1; % Increment time by 2 seconds
                            glucagonDose = 2; % Amount of glucagon to be injected
                            glucagonInjection = glucagonDose;
                            bloodSugarLevel = bloodSugarLevel + glucagonDose;

                            if g < 1
                                q = 0;
                                set(glucagonPlot, 'XData', [get(glucagonPlot, 'XData'), time], 'YData', [get(glucagonPlot, 'YData'), q]);
                                drawnow limitrate;
                            end
                            app.GlucagoneGauge.Value=glucagonInjection
                            set(glucagonPlot, 'XData', [get(glucagonPlot, 'XData'), time], 'YData', [get(glucagonPlot, 'YData'), glucagonInjection]);
                            drawnow limitrate;

                            time = time + 2;
                            set(bloodSugarPlot, 'XData', [get(bloodSugarPlot, 'XData'), time], 'YData', [get(bloodSugarPlot, 'YData'), bloodSugarLevel]);
                            drawnow limitrate;


                             app.Label_glucagone.Text = num2str(glucagonDose);
                            g = g + 1;
                    end

                end


                %%%%% when glucose == 75
                if bloodSugarLevel == 90
                    glucagonInjection = 0;
                    k = time -2;
                    set(glucagonPlot, 'XData', [get(glucagonPlot, 'XData'), k], 'YData', [get(glucagonPlot, 'YData'), glucagonInjection]);
                    drawnow limitrate;

                end

                % Display the final blood sugar level after the intervention

                for i = 1:2
                    pause(1);
                    time = time + 2; % Increment time by 2 seconds
                    set(bloodSugarPlot, 'XData', [get(bloodSugarPlot, 'XData'), time], 'YData', [get(bloodSugarPlot, 'YData'), bloodSugarLevel]);
                    drawnow limitrate;
                    app.Label_level.Text = num2str(bloodSugarLevel);
                end

                insulinInjection = 0;
                t2 = time;

                set(insulinPlot, 'XData', [get(insulinPlot, 'XData'), t2 ], 'YData', [get(insulinPlot, 'YData'), insulinInjection]);
                drawnow limitrate;

                glucagonInjection = 0;
                set(glucagonPlot, 'XData', [get(glucagonPlot, 'XData'), time], 'YData', [get(glucagonPlot, 'YData'), glucagonInjection]);
                drawnow limitrate;


                % Update axes limits to accommodate new data points
                % xlim([0, time+10]);
                % ylim([0, max(get(bloodSugarPlot, 'YData'))+10]);

                % Blood sugar level increments and decrements by 5 mmol/L every 5 seconds
                % bloodSugarLevel = bloodSugarLevel + 2;
                % time = time + 2; % Increment time by 5 seconds
                % Pause for 5 seconds
                % figure('Visible', 'on');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Lamp_Green = uilamp(app.UIFigure);
            app.InfusionPumpsystemLabel = uilabel(app.UIFigure);
            app.InfusionPumpsystemLabel.Text = 'Infusion Pump system';
            app.TextArea = uitextarea(app.UIFigure);
            app.GlucoseLevelLabel = uilabel(app.UIFigure);
            app.GlucoseLevelLabel.Text = 'Glucose Level';
            app.InsulinLevelLabel = uilabel(app.UIFigure);
            app.InsulinLevelLabel.Text = 'Insulin Level';
            app.GlucagoneLevelLabel = uilabel(app.UIFigure);
            app.GlucagoneLevelLabel.Text = 'Glucagone Level';
            app.Label_level = uilabel(app.UIFigure);
            app.Label_level.Text = '';
            app.Label_insulin = uilabel(app.UIFigure);
            app.Label_insulin.Text = '';
            app.Label_glucagone = uilabel(app.UIFigure);
            app.Label_glucagone.Text = '';
            app.mmolLLabel = uilabel(app.UIFigure);
            app.mmolLLabel.Text = 'mmol / L';
            app.mmolLLabel_2 = uilabel(app.UIFigure);
            app.mmolLLabel_2.Text = 'mmol / L';
            app.mmolLLabel_3 = uilabel(app.UIFigure);
            app.mmolLLabel_3.Text = 'mmol / L';
            app.GlucoseGaugeLabel = uilabel(app.UIFigure);
            app.GlucoseGaugeLabel.Text = 'Glucose';
            app.GlucoseGauge = uigauge(app.UIFigure, 'circular');
            app.GlucoseGauge.Limits = [0 200];
            app.InsulinGaugeLabel = uilabel(app.UIFigure);
            app.InsulinGaugeLabel.Text = 'Insulin';
            app.InsulinGauge = uigauge(app.UIFigure, 'linear');
            app.InsulinGauge.Limits = [0 10];
            app.GlucagoneGaugeLabel = uilabel(app.UIFigure);
            app.GlucagoneGaugeLabel.Text = 'Glucagone';
            app.GlucagoneGauge = uigauge(app.UIFigure, 'linear');
            app.GlucagoneGauge.Limits = [0 10];
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end